classdef NoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        NoiseAxes            matlab.ui.control.UIAxes
        StartEditFieldLabel  matlab.ui.control.Label
        StartEditField       matlab.ui.control.EditField
        EndEditFieldLabel    matlab.ui.control.Label
        EndEditField         matlab.ui.control.EditField
        TimeLabel            matlab.ui.control.Label
        CalculateButton      matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        ImportButton         matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        ButtonGroup          matlab.ui.container.ButtonGroup
        ImportfileButton     matlab.ui.control.RadioButton
        ImportfolderButton   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Patient;
    end
    
    methods (Access = private)
        
        function findWorkHours(app)
        % Needs fixing
           afterHoursTable = readtable('Data\Fyraften.csv');
           dataTable = afterHoursTable(afterHoursTable.PatientID == str2num(app.Patient.ID),:);
                 
           endOfWork = datetime(num2str(dataTable.Fyraften),'InputFormat','HH.mm',"Format","HH:mm:ss" );
           app.Patient.Work.Stop = endOfWork;
           
           app.Patient.Work.Start = app.Patient.Measurement(1,:).Time;
           app.Patient
           app.Patient.Work
           app.Patient.Company
        end
        
        function findCompanyInformation(app)
            keyTable = readtable('Data/Noeglefil.csv');
            dataTable = keyTable(keyTable.PatientID == str2num(app.Patient.ID), :);
            app.Patient.Company.ID = dataTable.VirksomhedsID;
            app.Patient.Company.Name = dataTable.VirksomhedsNavn;
        end
        
        function updateTextBoxes(app)
            outformat = 'HH:MM:SS'
            app.StartEditField.Value = datestr(app.Patient.Work.Start,outformat)
            app.EndEditField.Value = datestr(app.Patient.Work.Stop,outformat)
        end
        
        function calcuateSoundPressureLevel(app)
        
            measurementInterval = app.Patient.Measurement(...
                app.Patient.Measurement(:,:).Time >= datetime(app.StartEditField.Value,'InputFormat','HH:mm:ss') & ...
                app.Patient.Measurement(:,:).Time <= datetime(app.EndEditField.Value, 'InputFormat', 'HH:mm:ss'),:);
          
                % Sometimes readings can appear for two days. This makes
                % sure we only use date from the first day of reading.
            measurementInterval = measurementInterval(measurementInterval.Date == measurementInterval.Date(1),:)
            app.Patient.Work.Measurement = measurementInterval;
            
                nominator = 0;
                validReadings = 0;
                for i = 1:height(app.Patient.Work.Measurement)
                    value = app.Patient.Work.Measurement.Leq(i);
                     if (isnan(value) == false)
                         nominator = nominator + 10^(value/10);    
                         validReadings = validReadings + 1;
                     end
                end
                
                App.Patient.Work.Leq = log10(nominator/validReadings);
                test = App.Patient.Work.Leq
        end
        function updateGraph(app)
            plot(app.Patient.Work.Measurement.Time,app.Patient.Work.Measurement.Leq, 'Parent',app.NoiseAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [FileName, Pathname] = uigetfile();
            if FileName ~=0
                Fullpath = fullfile(Pathname, FileName);
                app.Patient.Measurement = importSingleFileFunction(Fullpath);


                IdCellArray = strsplit(FileName, '.'); %%Splitting Filename into two parts; Filename and file extension
                app.Patient.ID = IdCellArray{1,:};
                findCompanyInformation(app);
                findWorkHours(app);
                
                updateTextBoxes(app);
                calcuateSoundPressureLevel(app);
                updateGraph(app);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calcuateSoundPressureLevel(app);
            updateGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.NoiseAxes = uiaxes(app.Panel);
            title(app.NoiseAxes, 'Title')
            xlabel(app.NoiseAxes, 'Time(s)')
            ylabel(app.NoiseAxes, 'Sound Pressure Level[dB]')
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'text');
            app.EndEditFieldLabel = uilabel(app.Panel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Panel, 'text');
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ImportButton = uibutton(app.Panel_2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportfileButton = uiradiobutton(app.ButtonGroup);
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Value = true;
            app.ImportfolderButton = uiradiobutton(app.ButtonGroup);
            app.ImportfolderButton.Text = 'Import folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end